classdef KinderEggFigureDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TransferLearningButton  matlab.ui.control.Button
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        DecisionTreeButton      matlab.ui.control.Button
        KNNButton               matlab.ui.control.Button
        BilderstellenButton     matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        newImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BilderstellenButton
        function BilderstellenButtonPushed(app, event)
            app.newImage=getImage();
            imshow(app.newImage,parent=app.UIAxes);
          
        end

        % Button pushed function: KNNButton
        function KNNButtonPushed(app, event)
            predictedLabel=classificationKNN(app.newImage);
app.Label.Text=predictedLabel;
   files = dir(['./imageData/' char(predictedLabel) '/*.png']);
            imshow(imread(['./imageData/' char(predictedLabel) '/' files(1).name]),'Parent',app.UIAxes2);
        end

        % Button pushed function: DecisionTreeButton
        function DecisionTreeButtonPushed(app, event)
            predictedLabel=classificationDecisionTrees(app.newImage);
app.Label_2.Text=predictedLabel;
   files = dir(['./imageData/' char(predictedLabel) '/*.png']);
            imshow(imread(['./imageData/' char(predictedLabel) '/' files(1).name]),'Parent',app.UIAxes3);
        end

        % Button pushed function: TransferLearningButton
        function TransferLearningButtonPushed(app, event)
            predictedLabel=classificationTransferLearning(app.newImage);
app.Label_3.Text=predictedLabel;
   files = dir(['./imageData/' char(predictedLabel) '/*.png']);
            imshow(imread(['./imageData/' char(predictedLabel) '/' files(1).name]),'Parent',app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bildaufnahme')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.BilderstellenButton = uibutton(app.UIFigure, 'push');
            app.BilderstellenButton.ButtonPushedFcn = createCallbackFcn(app, @BilderstellenButtonPushed, true);
            app.BilderstellenButton.Text = 'Bild erstellen';
            app.KNNButton = uibutton(app.UIFigure, 'push');
            app.KNNButton.ButtonPushedFcn = createCallbackFcn(app, @KNNButtonPushed, true);
            app.KNNButton.Text = 'KNN';
            app.DecisionTreeButton = uibutton(app.UIFigure, 'push');
            app.DecisionTreeButton.ButtonPushedFcn = createCallbackFcn(app, @DecisionTreeButtonPushed, true);
            app.DecisionTreeButton.Text = 'Decision Tree';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.TransferLearningButton = uibutton(app.UIFigure, 'push');
            app.TransferLearningButton.ButtonPushedFcn = createCallbackFcn(app, @TransferLearningButtonPushed, true);
            app.TransferLearningButton.Text = 'Transfer Learning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KinderEggFigureDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end